classdef AudioEqualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Knob           matlab.ui.control.Knob
        Switch         matlab.ui.control.RockerSwitch
        Label_20       matlab.ui.control.Label
        KLabel_14      matlab.ui.control.Label
        KLabel_13      matlab.ui.control.Label
        KLabel_12      matlab.ui.control.Label
        KLabel_11      matlab.ui.control.Label
        KLabel_10      matlab.ui.control.Label
        KLabel_9       matlab.ui.control.Label
        KLabel_8       matlab.ui.control.Label
        KLabel_7       matlab.ui.control.Label
        KLabel_6       matlab.ui.control.Label
        KLabel_5       matlab.ui.control.Label
        KLabel_4       matlab.ui.control.Label
        KLabel_3       matlab.ui.control.Label
        KLabel_2       matlab.ui.control.Label
        KLabel         matlab.ui.control.Label
        Label_19       matlab.ui.control.Label
        Label_18       matlab.ui.control.Label
        Label_17       matlab.ui.control.Label
        Label_16       matlab.ui.control.Label
        Label_15       matlab.ui.control.Label
        Label_14       matlab.ui.control.Label
        Label_13       matlab.ui.control.Label
        Label_12       matlab.ui.control.Label
        Label_11       matlab.ui.control.Label
        Label_10       matlab.ui.control.Label
        Label_9        matlab.ui.control.Label
        Label_8        matlab.ui.control.Label
        Label_7        matlab.ui.control.Label
        Label_6        matlab.ui.control.Label
        Label_5        matlab.ui.control.Label
        Label_4        matlab.ui.control.Label
        Label_3        matlab.ui.control.Label
        dBLabel_6      matlab.ui.control.Label
        dBLabel_5      matlab.ui.control.Label
        dBLabel_4      matlab.ui.control.Label
        dBLabel_3      matlab.ui.control.Label
        dBLabel_2      matlab.ui.control.Label
        dBLabel        matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        Slider_32      matlab.ui.control.Slider
        Lamp           matlab.ui.control.Lamp
        RecButton      matlab.ui.control.Button
        DropDown       matlab.ui.control.DropDown
        StopButton     matlab.ui.control.Button
        PlayButton     matlab.ui.control.Button
        TrackDropDown  matlab.ui.control.DropDown
        Slider_31      matlab.ui.control.Slider
        Slider_30      matlab.ui.control.Slider
        Slider_29      matlab.ui.control.Slider
        Slider_28      matlab.ui.control.Slider
        Slider_27      matlab.ui.control.Slider
        Slider_26      matlab.ui.control.Slider
        Slider_25      matlab.ui.control.Slider
        Slider_24      matlab.ui.control.Slider
        Slider_23      matlab.ui.control.Slider
        Slider_22      matlab.ui.control.Slider
        Slider_21      matlab.ui.control.Slider
        Slider_20      matlab.ui.control.Slider
        Slider_19      matlab.ui.control.Slider
        Slider_18      matlab.ui.control.Slider
        Slider_17      matlab.ui.control.Slider
        Slider_16      matlab.ui.control.Slider
        Slider_15      matlab.ui.control.Slider
        Slider_14      matlab.ui.control.Slider
        Slider_13      matlab.ui.control.Slider
        Slider_12      matlab.ui.control.Slider
        Slider_11      matlab.ui.control.Slider
        Slider_10      matlab.ui.control.Slider
        Slider_9       matlab.ui.control.Slider
        Slider_8       matlab.ui.control.Slider
        Slider_7       matlab.ui.control.Slider
        Slider_6       matlab.ui.control.Slider
        Slider_5       matlab.ui.control.Slider
        Slider_4       matlab.ui.control.Slider
        Slider_3       matlab.ui.control.Slider
        Slider_2       matlab.ui.control.Slider
        Slider_1       matlab.ui.control.Slider
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        isRec = 0; 
        isPlay = 0;
        fs = 44100;
        fk = [10 20 22.4 25 28.2 31.5 35.5 40 44.7 50 56.2 63 ...
            70.8 80 89.1 100 112 125 141 160 178 200 224 250 ...
            282 315 355 400 447 500 562 630 708 800 891 1000 ...
            1120 1250 1410 1600 1780 2000 2240 2500 2820 ...
            3150 3550 4000 4470 5000 5620  6300 7080 8000 ...
            8910 10000 11200 12500 14100 16000 17800 20000];
           
        wn = 2*pi*[10 14 20 21.2 22.4 23.7 25 26.6 28.2 29.9 ...
            31.5 33.5 35.5 37.6 40 42.2 44.7 47.3 50 53.1 ...
            56.2 59.6 63 66.9  70.8 75 80 84.2 89.1 94.5 100 ...
            106 112 119 125 133 141 150 160 168 178 188 200 ...
            211 224 237 250 266 282 299 315 335 355  376 ...
            400 422 447 473 500 531 562 596 630 669 708 ...
            750 800 842 891 945 1000 1060 1120 1190 1250 ...
            1330 1410 1500 1600 1680  1780 1880 2000 2110 ...
            2240 2370 2500 2660 2820 2990 3150 3350 ...
            3550 3760 4000 4220 4470 4730 5000 5310 5620 5960 ...
            6300 6690 7080 7500 8000 8420 8910 9450 10000 ...
            10600 11200 11900 12500 13300 14100 15000 16000 ...
            16800 17800 18800 20000];
     
         fg = [20 25 31.5 40 50 63 80 100 125 160 200 250 ...
             315 400 500  630 800 1000 1250 1600 2000 2500 ...
             3150 4000 5000 6300 8000 ...
             10000 12500 16000 20000];
         
        isStop = 0; 
        denom = zeros(62,3);
        ff = 0;
    end
    
    methods (Access = private)
        
        function a = den(app,k,Fs)
            thetak = 2*pi*app.fk(k)/Fs;
            if k>1 && k<62
            dthetak = (2*pi*app.fk(k+1)/Fs-2*pi*app.fk(k-1)/Fs)/2;
            elseif k == 1
                dthetak = 2*pi*app.fk(2)/Fs-2*pi*app.fk(1)/Fs;
            else 
                dthetak = 2*pi*app.fk(62)/Fs-2*pi*app.fk(61)/Fs;
            end
            pk = exp(-dthetak/2);
            a = [1 -2*pk*cos(thetak) pk^2];
      
        end
        
        function Mrplus = Mrp(app,Fs)
            
            M = zeros(123,124);
            M(:,125) = ones(123,1);
            sqW = Weight(app,app.Slider_1.Value, ...
                app.Slider_2.Value,...
                app.Slider_3.Value,app.Slider_4.Value,...
                app.Slider_5.Value,app.Slider_6.Value,...
                app.Slider_7.Value,app.Slider_8.Value,...
                app.Slider_9.Value,app.Slider_10.Value,...
                app.Slider_11.Value,app.Slider_12.Value,...
                app.Slider_13.Value,app.Slider_14.Value,...
                app.Slider_15.Value,app.Slider_16.Value,...
                app.Slider_17.Value,app.Slider_18.Value,...
                app.Slider_19.Value,app.Slider_20.Value,...
                app.Slider_21.Value,app.Slider_22.Value,...
               app.Slider_23.Value,app.Slider_24.Value,...
               app.Slider_25.Value,app.Slider_26.Value,...
               app.Slider_27.Value,app.Slider_28.Value,...
               app.Slider_29.Value,app.Slider_30.Value,...
               app.Slider_31.Value);
           
            for n = 1:123
                for k = 1:62
                   M(n,2*k-1) = 1/(app.denom(k,:)*...
                       [1; exp(-app.wn(n)/Fs*1i); ...
                       exp(-2*app.wn(n)/Fs*1i)]);
                       M(n,2*k) = exp(-app.wn(n)/Fs*1i)/...
                       (app.denom(k,:)*[1; exp(-app.wn(n)/Fs*1i);...
                       exp(-2*app.wn(n)/Fs*1i)]);
                end
                M(n,:) = M(n,:)*sqW(n);
            end
  
            Mr = [real(M);imag(M)];
            
            Mrplus = (transpose(Mr)*Mr)\transpose(Mr);
        
        end
        
        function htr = target(app,G1,G2,G3,G4,G5,G6,G7,...
            G8,G9,G10,G11,G12,G13,G14,G15,G16,G17,G18,G19,...
            G20,G21,G22,G23,G24,G25,G26,G27,G28,G29,G30,G31)
            
            y = 10.^(1/20*pchip([-flip(app.fg) app.fg],...
                [flip([G1,G2,G3,G4,G5,G6,G7,G8,G9,G10,...
                G11,G12,G13,G14,G15,G16,G17,G18,G19,G20,...
                G21,G22,G23,G24,G25,G26,G27,G28,G29,G30,G31])...
                [G1,G2,G3,G4,G5,G6,G7,G8,G9,G10,G11,G12,...
                G13,G14,G15,G16,G17,G18,G19,G20,G21,G22,...
                G23,G24,G25,G26,G27,G28,G29,G30,G31]],...
                linspace(-app.fs/2,app.fs/2,2^16)))';
            
            phase = unwrap(imag(-hilbert(log(y))));
            phase = phase(32769:64124);

            i = 1;
            fi = zeros(1,123);
            for w = app.wn/(2*pi)
                if round(w*length(phase)/21100) > length(phase)
                    fi(i) = phase(length(phase));
                else
                fi(i) = phase(round(w*length(phase)/21100));
                end
                i=i+1;
            end
   
            htr = [real(exp(1i*fi'));imag(exp(1i*fi'))];
          
        end
        
        function popt = num(app,htr,Mrplus)
            popt = Mrplus*htr;
            
        end
        
        function yk = filterNew(app,bWithIndex,xk1,...
            ybuffer,xbuffer,Fs,n)
            yk = filter(bWithIndex',app.denom(n,:),...
                xk1,filtic(bWithIndex',app.denom(n,:),...
                ybuffer,xbuffer));
            
            
        end
        
        function sqW = Weight(app,G1,G2,G3,G4,G5,G6,G7,G8,G9,...
            G10,G11,G12,G13,G14,G15,G16,G17,G18,G19,G20,G21,...
            G22,G23,G24,G25,G26,G27,G28,G29,G30,G31)
            ht = 10.^(1/20*pchip([10 app.fg],[G1 G1,G2,G3,G4,...
                G5,G6,G7,G8,G9,G10,G11,G12,G13,G14,G15,G16,...
                G17,G18,G19,G20,G21,G22,G23,G24,G25,G26,G27,...
                G28,G29,G30,G31],app.wn/(2*pi)))';
            sqW1 = zeros(123,1);
            for i = 1:123
                sqW1(i) = 1/ht(i);
            end
            sqW = sqW1;
        end
        end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app)

            app.Lamp.Enable = 'off';
            tracks = struct2cell(dir('*.mp3'));
            tracks = tracks(1,:);
            app.TrackDropDown.Items = tracks;
            wav = struct2cell(dir('*.wav'));
            wav = wav(1,:);
            for i = 1:length(wav)
                app.TrackDropDown.Items(i+length(tracks(1,:)))...
                    = wav(i);
            end
            if isempty(app.TrackDropDown.Items) == 1
                uialert(app.UIFigure,['Your current folder ...' ...
                    'does not contain any audio files'],...
                    'Info','Icon','info');
            end
            tot = audioinfo(char(app.TrackDropDown.Value));
                  l = round(tot.Duration);
            if mod(l,60) >= 10
               app.Label_2.Text = strcat(num2str(floor(l/60)),':',num2str(mod(l,60)));
               else
               app.Label_2.Text = strcat(num2str(floor(l/60)),':0',num2str(mod(l,60)));
            end
        end

        % Button pushed function: PlayButton
        function play(app, event)
        if isempty(app.TrackDropDown.Items) == 1
            uialert(app.UIFigure,'No audio file found',...
                'Error','Icon','error');
        elseif app.isRec == 1        
            uialert(app.UIFigure,...
                'Stop recording before playback of a file',...
                'Tip','Icon','warning');
            
        elseif strcmp(app.PlayButton.Text,'Play') && ...
                app.isPlay == 0 && app.isRec == 0
            
            fileReader = dsp.AudioFileReader(...
                char(app.TrackDropDown.Value),...
                'SamplesPerFrame',1024);
            deviceWriter = audioDeviceWriter('SampleRate',...
                fileReader.SampleRate);
            app.PlayButton.Text = 'Pause';
            app.isPlay = 1;
            app.fs = fileReader.SampleRate;
            Fs = app.fs;
            
            for k = 1:62
                app.denom(k,:) = den(app,k,Fs);
            end
              xbuffer1 = 0;
              xbuffer2 = 0;
              ybuffer1 = zeros(62,3);
              ybuffer2 = zeros(62,3);

            S1 = app.Slider_1.Value;
            S2 = app.Slider_2.Value;
            S3 = app.Slider_3.Value; 
            S4 = app.Slider_4.Value;
            S5 = app.Slider_5.Value;
            S6 = app.Slider_6.Value;
            S7 = app.Slider_7.Value;
            S8 = app.Slider_8.Value; 
            S9 = app.Slider_9.Value;
            S10 = app.Slider_10.Value;
            S11 = app.Slider_11.Value;
            S12 = app.Slider_12.Value;
            S13 = app.Slider_13.Value; 
            S14 = app.Slider_14.Value;
            S15 = app.Slider_15.Value;
            S16 = app.Slider_16.Value;
            S17 = app.Slider_17.Value;
            S18 = app.Slider_18.Value;
            S19 = app.Slider_19.Value;
            S20 = app.Slider_20.Value; 
            S21 = app.Slider_21.Value;
            S22 = app.Slider_22.Value;
            S23 = app.Slider_23.Value;
            S24 = app.Slider_24.Value;
            S25 = app.Slider_25.Value;
            S26 = app.Slider_26.Value;
            S27 = app.Slider_27.Value; 
            S28 = app.Slider_28.Value;
            S29 = app.Slider_29.Value;
            S30 = app.Slider_30.Value;
            S31 = app.Slider_31.Value;
    
            Mrplus = Mrp(app,Fs);
  
           b = num(app,target(app,S1,S2,S3,S4,S5,S6,S7,S8,S9, ...
               S10,S11,S12,S13,S14,S15,S16,S17,S18,S19,S20,S21,S22,...
               S23,S24,S25,S26,S27,S28,S29,S30,S31),Mrplus);
           
           dF = Fs/1024;
           f = -Fs/2:dF:Fs/2-dF; 
           
           i = 0;
           tot = audioinfo(char(app.TrackDropDown.Value));

           
           tot = tot.TotalSamples;
           
           i = 0;
           ff = app.ff;
try
    processedSignal = [];
while ~isDone(fileReader)
    xk = fileReader();
    if length(xk(1,:))~=2
        xk = [xk,xk];
        xk1 = xk(:,1)';
        xk2 = xk(:,2)';
    else
    xk1 = xk(:,1)';
    xk2 = xk(:,2)';
    end

    
    if strcmp(app.PlayButton.Text,'Play') == 1
        while strcmp(app.PlayButton.Text,'Play') == 1 && ...
                app.isStop == 0
            
            pause(1);
        end
    
    end
    

    pause(0);
    
    if app.Slider_1.Value ~= S1 || app.Slider_2.Value ~= S2 ||...
            app.Slider_3.Value ~= S3 ||...
            S4 ~= app.Slider_4.Value ||...
            S5 ~= app.Slider_5.Value || ...
          app.Slider_6.Value ~= S6 || ...
          app.Slider_7.Value ~= S7 || ...
          app.Slider_8.Value ~= S8 || ...
          S9 ~= app.Slider_9.Value || ...
          S10 ~= app.Slider_10.Value || ... 
          app.Slider_11.Value ~= S11 || ...
          app.Slider_12.Value ~= S12 || ...
          app.Slider_13.Value ~= S13 || ...
          S14 ~= app.Slider_14.Value || ...
          S15 ~= app.Slider_15.Value || ... 
          app.Slider_16.Value ~= S16 || ...
          app.Slider_17.Value ~= S17 || ...
          app.Slider_18.Value ~= S18 || ...
          S19 ~= app.Slider_19.Value || ...
          S20 ~= app.Slider_20.Value || ... 
          app.Slider_21.Value ~= S21 || ...
          app.Slider_22.Value ~= S22 || ...
          app.Slider_23.Value ~= S23 || ...
          S24 ~= app.Slider_24.Value || ...
          S25 ~= app.Slider_25.Value || ... 
          app.Slider_26.Value ~= S26 || ...
          app.Slider_27.Value ~= S27 || ...
          app.Slider_28.Value ~= S28 || ...
          S29 ~= app.Slider_29.Value || ...
          S30 ~= app.Slider_30.Value || ... 
          app.Slider_31.Value ~= S31
      
            S1 = app.Slider_1.Value;
            S2 = app.Slider_2.Value;
            S3 = app.Slider_3.Value; 
            S4 = app.Slider_4.Value;
            S5 = app.Slider_5.Value;
            S6 = app.Slider_6.Value;
            S7 = app.Slider_7.Value;
            S8 = app.Slider_8.Value; 
            S9 = app.Slider_9.Value;
            S10 = app.Slider_10.Value;
            S11 = app.Slider_11.Value;
            S12 = app.Slider_12.Value;
            S13 = app.Slider_13.Value; 
            S14 = app.Slider_14.Value;
            S15 = app.Slider_15.Value;
            S16 = app.Slider_16.Value;
            S17 = app.Slider_17.Value;
            S18 = app.Slider_18.Value;
            S19 = app.Slider_19.Value;
            S20 = app.Slider_20.Value; 
            S21 = app.Slider_21.Value;
            S22 = app.Slider_22.Value;
            S23 = app.Slider_23.Value;
            S24 = app.Slider_24.Value;
            S25 = app.Slider_25.Value;
            S26 = app.Slider_26.Value;
            S27 = app.Slider_27.Value; 
            S28 = app.Slider_28.Value;
            S29 = app.Slider_29.Value;
            S30 = app.Slider_30.Value;
            S31 = app.Slider_31.Value;
        
        Mrplus = Mrp(app,Fs);
        b = num(app,target(app,S1,S2,S3,S4,S5,S6,S7,S8,S9, ...
               S10,S11,S12,S13,S14,S15,S16,S17,S18,S19,S20,...
               S21,S22, S23,S24,S25,S26,S27,S28,S29,S30,S31),...
               Mrplus);
        
        
    end
        
    
       for n=1:63
           if n<63
               ykNew1(n,:) = filterNew(app,b((2*n-1):(2*n)),...
                   xk1,ybuffer1(n,:),xbuffer1,Fs,n);
               ykNew2(n,:) = filterNew(app,b((2*n-1):(2*n)),...
                   xk2,ybuffer2(n,:),xbuffer2,Fs,n);
           else
               ykNew1(n,:) = xk1*b(125);
               ykNew2(n,:) = xk2*b(125);
           end

       end
    
       yk1=0;
       yk2=0;
       for n=1:63
           yk1=yk1 + ykNew1(n,:);
     
           yk2=yk2 + ykNew2(n,:);
       end

    deviceWriter([0.23*yk1',0.23*yk2']);
    processedSignal = [processedSignal; [0.23 * yk1', 0.23 * yk2']];
    xbuffer1 = flip(xk1(length(xk1)-1:length(xk1)));
    xbuffer2 = flip(xk2(length(xk2)-1:length(xk2)));
   
   for n=1:62

            ybuffer1(n,:)=flip(ykNew1(n,...
                (length(ykNew1(n,:))-2):(length(ykNew1(n,:)))));

            ybuffer2(n,:)=flip(ykNew2(n,...
                (length(ykNew2(n,:))-2):(length(ykNew2(n,:)))));

   end
   if app.ff ~= ff
        ff = app.ff;
        fileReader = dsp.AudioFileReader(char(app.TrackDropDown.Value),'SamplesPerFrame',1024);
            deviceWriter = audioDeviceWriter('SampleRate',fileReader.SampleRate);
            i = 0;
            for i = 0:round(app.Slider_32.Value*tot/1024)
                xk = fileReader();
                i = i+1;
            end
   end
            if mod(i,18) == 0
       app.Slider_32.Value = i*1024/tot;
       l = round(i*1024/Fs);
if mod(l,60) >= 10
app.Label.Text = strcat(num2str(floor(l/60)),':',num2str(mod(l,60)));
else
 app.Label.Text = strcat(num2str(floor(l/60)),':0',num2str(mod(l,60)));
end
            end
    
    if mod(i,51-round(app.Knob.Value))==0 && ...
            strcmp(app.Switch.Value,'On') == 1
    z = fftshift(fft(yk1));
    fac = zeros(1,512);
    for n = 1:512
        fac(n) = 0.5+n/100;
    end
    
    p = abs(z)/1024;
    p(513:1024) = p(513:1024).*fac;
    plot(app.UIAxes,f,p,'LineWidth',1.2)
    drawnow limitrate;
    
    end
    
    if app.isStop == 1
                release(fileReader);
                release(deviceWriter);
                outputFileName = 'processed_audio.wav';
                audiowrite(outputFileName, processedSignal, Fs);
            uialert(app.UIFigure, ['Processed audio saved to ', outputFileName], ...
                                    'Info', 'Icon', 'info');
                app.PlayButton.Text = 'Play';
                app.isPlay = 0;
                app.isStop = 0;
                app.Label.Text = '0:00';
                app.Slider_32.Value = 0;
    end
  
    i = i+1;

end
app.PlayButton.Text = 'Play';
app.isPlay = 0;
app.Label.Text = '0:00';
app.Slider_32.Value = 0;
release(fileReader);
release(deviceWriter);

catch
end
            elseif strcmp(app.PlayButton.Text,'Play') && ...
                    app.isPlay == 1
                app.PlayButton.Text = 'Pause';
            elseif app.isStop == 1
                release(fileReader);
                release(deviceWriter);
                app.PlayButton.Text = 'Play';
                app.isPlay = 0;
                app.isStop = 0;
                app.Label.Text = '0:00';
                app.Slider_32.Value = 0;
                
            else
                
                app.PlayButton.Text = 'Play';
            end
        
        end

        % Button pushed function: RecButton
        function Rec(app, event)
           if app.isPlay == 1
            uialert(app.UIFigure,...
                'Stop playback before recordning',...
                'Tip','Icon','warning');
           elseif app.isRec == 0
               
            deviceReader = audioDeviceReader(44100,1024,...
                'NumChannels',2);
                deviceWriter = audioDeviceWriter('SampleRate',...
                    deviceReader.SampleRate);
                app.Lamp.Enable = 'on';
                app.isRec = 1;
                app.RecButton.Text = 'Stop';
                 xbuffer1 = 0;
              xbuffer2 = 0;
              ybuffer1 = zeros(62,3);
              ybuffer2 = zeros(62,3);
              
                        
            S1 = app.Slider_1.Value;
            S2 = app.Slider_2.Value;
            S3 = app.Slider_3.Value; 
            S4 = app.Slider_4.Value;
            S5 = app.Slider_5.Value;
            S6 = app.Slider_6.Value;
            S7 = app.Slider_7.Value;
            S8 = app.Slider_8.Value; 
            S9 = app.Slider_9.Value;
            S10 = app.Slider_10.Value;
            S11 = app.Slider_11.Value;
            S12 = app.Slider_12.Value;
            S13 = app.Slider_13.Value; 
            S14 = app.Slider_14.Value;
            S15 = app.Slider_15.Value;
            S16 = app.Slider_16.Value;
            S17 = app.Slider_17.Value;
            S18 = app.Slider_18.Value;
            S19 = app.Slider_19.Value;
            S20 = app.Slider_20.Value; 
            S21 = app.Slider_21.Value;
            S22 = app.Slider_22.Value;
            S23 = app.Slider_23.Value;
            S24 = app.Slider_24.Value;
            S25 = app.Slider_25.Value;
            S26 = app.Slider_26.Value;
            S27 = app.Slider_27.Value; 
            S28 = app.Slider_28.Value;
            S29 = app.Slider_29.Value;
            S30 = app.Slider_30.Value;
            S31 = app.Slider_31.Value;
          
            Fs=44100;
            app.fs = Fs;
            for k = 1:62
                app.denom(k,:) = den(app,k,Fs);
            end
            Mrplus = Mrp(app,Fs);
  
           b = num(app,target(app,S1,S2,S3,S4,S5,S6,S7,S8,S9, ...
               S10,S11,S12,S13,S14,S15,S16,S17,S18,S19,S20,S21,S22,...
               S23,S24,S25,S26,S27,S28,S29,S30,S31),Mrplus);
 
           dF = Fs/1024;
           f = -Fs/2:dF:Fs/2-dF; 
           
           i = 1;
RecordedData = [];
while app.isRec == 1
    xk = deviceReader();
    
    xk1 = xk(:,1)';
    xk2 = xk(:,2)';
 
    pause(0);
    
     if app.Slider_1.Value ~= S1 || app.Slider_2.Value ~= S2 ||...
          app.Slider_3.Value ~= S3 || S4 ~= app.Slider_4.Value ||...
          S5 ~= app.Slider_5.Value || app.Slider_6.Value ~= S6 ||...
          app.Slider_7.Value ~= S7 || app.Slider_8.Value ~= S8 ||...
          S9 ~= app.Slider_9.Value || S10 ~= app.Slider_10.Value || ... 
          app.Slider_11.Value ~= S11 ||...
          app.Slider_12.Value ~= S12 ||...
          app.Slider_13.Value ~= S13 ||...
          S14 ~= app.Slider_14.Value ||...
          S15 ~= app.Slider_15.Value ||... 
          app.Slider_16.Value ~= S16 ||...
          app.Slider_17.Value ~= S17 ||...
          app.Slider_18.Value ~= S18 ||...
          S19 ~= app.Slider_19.Value ||...
          S20 ~= app.Slider_20.Value ||... 
          app.Slider_21.Value ~= S21 ||...
          app.Slider_22.Value ~= S22 ||...
          app.Slider_23.Value ~= S23 ||...
          S24 ~= app.Slider_24.Value ||...
          S25 ~= app.Slider_25.Value ||... 
          app.Slider_26.Value ~= S26 ||...
          app.Slider_27.Value ~= S27 ||...
          app.Slider_28.Value ~= S28 ||...
          S29 ~= app.Slider_29.Value ||...
          S30 ~= app.Slider_30.Value ||... 
          app.Slider_31.Value ~= S31
      
            S1 = app.Slider_1.Value;
            S2 = app.Slider_2.Value;
            S3 = app.Slider_3.Value; 
            S4 = app.Slider_4.Value;
            S5 = app.Slider_5.Value;
            S6 = app.Slider_6.Value;
            S7 = app.Slider_7.Value;
            S8 = app.Slider_8.Value; 
            S9 = app.Slider_9.Value;
            S10 = app.Slider_10.Value;
            S11 = app.Slider_11.Value;
            S12 = app.Slider_12.Value;
            S13 = app.Slider_13.Value; 
            S14 = app.Slider_14.Value;
            S15 = app.Slider_15.Value;
            S16 = app.Slider_16.Value;
            S17 = app.Slider_17.Value;
            S18 = app.Slider_18.Value;
            S19 = app.Slider_19.Value;
            S20 = app.Slider_20.Value; 
            S21 = app.Slider_21.Value;
            S22 = app.Slider_22.Value;
            S23 = app.Slider_23.Value;
            S24 = app.Slider_24.Value;
            S25 = app.Slider_25.Value;
            S26 = app.Slider_26.Value;
            S27 = app.Slider_27.Value; 
            S28 = app.Slider_28.Value;
            S29 = app.Slider_29.Value;
            S30 = app.Slider_30.Value;
            S31 = app.Slider_31.Value;
     
        Mrplus = Mrp(app,Fs);
        b = num(app,target(app,S1,S2,S3,S4,S5,S6,S7,S8,S9, ...
               S10,S11,S12,S13,S14,S15,S16,S17,S18,S19,S20,S21,S22,...
               S23,S24,S25,S26,S27,S28,S29,S30,S31),Mrplus);
    
    end
   
       for n=1:63
           if n<63
           
               ykNew1(n,:) = filterNew(app,...
                   b((2*n-1):(2*n)),xk1,ybuffer1(n,:),xbuffer1,Fs,n);
               ykNew2(n,:) = filterNew(app,...
                   b((2*n-1):(2*n)),xk2,ybuffer2(n,:),xbuffer2,Fs,n);
           else
               ykNew1(n,:) = xk1*b(125);
               ykNew2(n,:) = xk2*b(125);
           
           end

       end
    
       yk1=0;
       yk2=0;
       for n=1:63
           yk1=yk1 + ykNew1(n,:);
     
           yk2=yk2 + ykNew2(n,:);
       end
 
    deviceWriter([0.24*yk1',0.24*yk2']);

    RecordedData = [RecordedData; [0.24*yk1', 0.24*yk2']];

    xbuffer1 = flip(xk1(length(xk1)-1:length(xk1)));
    xbuffer2 = flip(xk2(length(xk2)-1:length(xk2)));
   
   for n=1:62
            ybuffer1(n,:)=flip(ykNew1(n,...
                (length(ykNew1(n,:))-2):(length(ykNew1(n,:)))));
            ybuffer2(n,:)=flip(ykNew2(n,...
                (length(ykNew2(n,:))-2):(length(ykNew2(n,:)))));
    end

    if mod(i,51-round(app.Knob.Value))==0 && ...
            strcmp(app.Switch.Value,'On') == 1
    z = fftshift(fft(yk1));
    area(app.UIAxes,f,abs(z)/1024)
    drawnow limitrate;
    end
    
    if  mod(i,15) == 0 
        if strcmp(app.Lamp.Enable,'on') == 1 
        app.Lamp.Enable = 'off';
        else
        app.Lamp.Enable = 'on';
        end
    end

    i = i+1;
    
    end
release(deviceReader);
release(deviceWriter);

outputFile = 'recorded_audio.wav';
audiowrite(outputFile, RecordedData, Fs);
uialert(app.UIFigure, ['Recording saved to ', outputFile], ...
    'Info', 'Icon', 'info');
         else 
            app.isRec = 0;
            app.RecButton.Text = 'Rec';
            end
                
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            app.isStop = 1;
        end

        % Value changed function: DropDown
        function Preset(app, event)
            value = app.DropDown.Value;
            if strcmp('Rock',value) == 1
            app.Slider_1.Value = 7;
            app.Slider_2.Value = 7;app.Slider_3.Value = 6;
            app.Slider_4.Value = 6;app.Slider_5.Value = 5;
            app.Slider_6.Value = 5;app.Slider_7.Value = 5;
            app.Slider_8.Value = 3;app.Slider_9.Value = 2;
            app.Slider_10.Value = 1;app.Slider_11.Value = 0;
            app.Slider_12.Value = 0;app.Slider_13.Value = -1;
            app.Slider_14.Value = -1;app.Slider_15.Value = -2;
            app.Slider_16.Value = -3;app.Slider_17.Value = -3;
            app.Slider_18.Value = -4;app.Slider_19.Value = -3;
            app.Slider_20.Value = -3;app.Slider_21.Value = -2;
            app.Slider_22.Value = -1;app.Slider_23.Value = 0;
            app.Slider_24.Value = 2;app.Slider_25.Value = 3;
            app.Slider_26.Value = 4;app.Slider_27.Value = 5;
            app.Slider_28.Value = 5;app.Slider_29.Value = 5;
            app.Slider_30.Value = 5;app.Slider_31.Value = 4;
            
            elseif strcmp('Flat',value) == 1
                app.Slider_1.Value = 0;
            app.Slider_2.Value = 0;app.Slider_3.Value = 0;
            app.Slider_4.Value = 0;app.Slider_5.Value = 0;
            app.Slider_6.Value = 0;app.Slider_7.Value = 0;
            app.Slider_8.Value = 0;app.Slider_9.Value = 0;
            app.Slider_10.Value = 0;app.Slider_11.Value = 0;
            app.Slider_12.Value = 0;app.Slider_13.Value = 0;
            app.Slider_14.Value = 0;app.Slider_15.Value = 0;
            app.Slider_16.Value = 0;app.Slider_17.Value = 0;
            app.Slider_18.Value = 0;app.Slider_19.Value = 0;
            app.Slider_20.Value = 0;app.Slider_21.Value = 0;
            app.Slider_22.Value = 0;app.Slider_23.Value = 0;
            app.Slider_24.Value = 0;app.Slider_25.Value = 0;
            app.Slider_26.Value = 0;app.Slider_27.Value = 0;
            app.Slider_28.Value = 0;app.Slider_29.Value = 0;
            app.Slider_30.Value = 0;app.Slider_31.Value = 0;
   
            elseif strcmp('Pop',value) == 1
                app.Slider_1.Value = 0;
            app.Slider_2.Value = 0;app.Slider_3.Value = 0;
            app.Slider_4.Value = 0;app.Slider_5.Value = 1;
            app.Slider_6.Value = 1;app.Slider_7.Value = 2;
            app.Slider_8.Value = 3;app.Slider_9.Value = 4;
            app.Slider_10.Value = 4;app.Slider_11.Value = 4;
            app.Slider_12.Value = 3;app.Slider_13.Value = 2;
            app.Slider_14.Value = 1;app.Slider_15.Value = 0;
            app.Slider_16.Value = 0;app.Slider_17.Value = 0;
            app.Slider_18.Value = -1;app.Slider_19.Value = -1;
            app.Slider_20.Value = -2;app.Slider_21.Value = -2;
            app.Slider_22.Value = -2;app.Slider_23.Value = -2;
            app.Slider_24.Value = -1;app.Slider_25.Value = -1;
            app.Slider_26.Value = 0;app.Slider_27.Value = 1;
            app.Slider_28.Value = 0;app.Slider_29.Value = -1;
            app.Slider_30.Value = -1;app.Slider_31.Value = -1;
            
            elseif strcmp('Bass',value) == 1
                app.Slider_1.Value = 12;
            app.Slider_2.Value = 12;app.Slider_3.Value = 12;
            app.Slider_4.Value = 12;app.Slider_5.Value = 12;
            app.Slider_6.Value = 12;app.Slider_7.Value = 12;
            app.Slider_8.Value = 12;app.Slider_9.Value = 11;
            app.Slider_10.Value = 10;app.Slider_11.Value = 9;
            app.Slider_12.Value = 8;app.Slider_13.Value = 6;
            app.Slider_14.Value = 5;app.Slider_15.Value = 3;
            app.Slider_16.Value = 1;app.Slider_17.Value = -1;
            app.Slider_18.Value = -3;app.Slider_19.Value = -4;
            app.Slider_20.Value = -5;app.Slider_21.Value = -6;
            app.Slider_22.Value = -7;app.Slider_23.Value = -8;
            app.Slider_24.Value = -9;app.Slider_25.Value = -9;
            app.Slider_26.Value = -10;app.Slider_27.Value = -11;
            app.Slider_28.Value = -11;app.Slider_29.Value = -11;
            app.Slider_30.Value = -11;app.Slider_31.Value = -11;
            
            elseif strcmp('Treble',value) == 1
                app.Slider_1.Value = -12;
            app.Slider_2.Value = -11;app.Slider_3.Value = -10;
            app.Slider_4.Value = -10;app.Slider_5.Value = -10;
            app.Slider_6.Value = -10;app.Slider_7.Value = -9;
            app.Slider_8.Value = -8;app.Slider_9.Value = -7;
            app.Slider_10.Value = -7;app.Slider_11.Value = -7;
            app.Slider_12.Value = -6;app.Slider_13.Value = -6;
            app.Slider_14.Value = -5;app.Slider_15.Value = -5;
            app.Slider_16.Value = -3;app.Slider_17.Value = -2;
            app.Slider_18.Value = 0;app.Slider_19.Value = 3;
            app.Slider_20.Value = 5;app.Slider_21.Value = 6;
            app.Slider_22.Value = 8;app.Slider_23.Value = 9;
            app.Slider_24.Value = 9;app.Slider_25.Value = 10;
            app.Slider_26.Value = 11;app.Slider_27.Value = 12;
            app.Slider_28.Value = 12;app.Slider_29.Value = 12;
            app.Slider_30.Value = 12;app.Slider_31.Value = 12;
            
            elseif strcmp('Vocal',value) == 1
                app.Slider_1.Value = -8;
            app.Slider_2.Value = -7;app.Slider_3.Value = -6;
            app.Slider_4.Value = -5;app.Slider_5.Value = -5;
            app.Slider_6.Value = -4;app.Slider_7.Value = -3;
            app.Slider_8.Value = -3;app.Slider_9.Value = 0;
            app.Slider_10.Value = 0;app.Slider_11.Value = 2;
            app.Slider_12.Value = 5;app.Slider_13.Value = 6;
            app.Slider_14.Value = 7;app.Slider_15.Value = 7;
            app.Slider_16.Value = 7;app.Slider_17.Value = 7;
            app.Slider_18.Value = 7;app.Slider_19.Value = 7;
            app.Slider_20.Value = 6;app.Slider_21.Value = 4;
            app.Slider_22.Value = 3;app.Slider_23.Value = 2;
            app.Slider_24.Value = 0;app.Slider_25.Value = -2;
            app.Slider_26.Value = -5;app.Slider_27.Value = -5;
            app.Slider_28.Value = -5;app.Slider_29.Value = -5;
            app.Slider_30.Value = -5;app.Slider_31.Value = -7;
            
            elseif strcmp('Classical',value) == 1
                app.Slider_1.Value = -3;
            app.Slider_2.Value = -2;app.Slider_3.Value = -1;
            app.Slider_4.Value = 0;app.Slider_5.Value = 1;
            app.Slider_6.Value = 1;app.Slider_7.Value = 1;
            app.Slider_8.Value = 2;app.Slider_9.Value = 2;
            app.Slider_10.Value = 3;app.Slider_11.Value = 3;
            app.Slider_12.Value = 2;app.Slider_13.Value = 1;
            app.Slider_14.Value = 0;app.Slider_15.Value = -1;
            app.Slider_16.Value = -2;app.Slider_17.Value = -4;
            app.Slider_18.Value = -6;app.Slider_19.Value = -8;
            app.Slider_20.Value = -8;app.Slider_21.Value = -5;
            app.Slider_22.Value = -3;app.Slider_23.Value = -1;
            app.Slider_24.Value = 0;app.Slider_25.Value = 1;
            app.Slider_26.Value = 2;app.Slider_27.Value = 4;
            app.Slider_28.Value = 3;app.Slider_29.Value = 1;
            app.Slider_30.Value = 0;app.Slider_31.Value = -1;
            
            elseif strcmp('Hip-Hop',value) == 1
                app.Slider_1.Value = 4;
            app.Slider_2.Value = 4;app.Slider_3.Value = 4;
            app.Slider_4.Value = 4;app.Slider_5.Value = 3;
            app.Slider_6.Value = 3;app.Slider_7.Value = 2;
            app.Slider_8.Value = 1;app.Slider_9.Value = 1;
            app.Slider_10.Value = 1;app.Slider_11.Value = 0;
            app.Slider_12.Value = 0;app.Slider_13.Value = -1;
            app.Slider_14.Value = -1;app.Slider_15.Value = -2;
            app.Slider_16.Value = -2;app.Slider_17.Value = -3;
            app.Slider_18.Value = -3;app.Slider_19.Value = -2;
            app.Slider_20.Value = -2;app.Slider_21.Value = -1;
            app.Slider_22.Value = 0;app.Slider_23.Value = 1;
            app.Slider_24.Value = 1;app.Slider_25.Value = 2;
            app.Slider_26.Value = 3;app.Slider_27.Value = 3;
            app.Slider_28.Value = 3;app.Slider_29.Value = 4;
            app.Slider_30.Value = 3;app.Slider_31.Value = 2;
            
            elseif strcmp('Dance',value) == 1
                app.Slider_1.Value = 6;
            app.Slider_2.Value = 6;app.Slider_3.Value = 6;
            app.Slider_4.Value = 6;app.Slider_5.Value = 6;
            app.Slider_6.Value = 7;app.Slider_7.Value = 6;
            app.Slider_8.Value = 4;app.Slider_9.Value = 2;
            app.Slider_10.Value = 1;app.Slider_11.Value = 1;
            app.Slider_12.Value = 0;app.Slider_13.Value = -1;
            app.Slider_14.Value = -2;app.Slider_15.Value = -2;
            app.Slider_16.Value = -1;app.Slider_17.Value = -1;
            app.Slider_18.Value = 0;app.Slider_19.Value = 1;
            app.Slider_20.Value = 1;app.Slider_21.Value = 2;
            app.Slider_22.Value = 3;app.Slider_23.Value = 2;
            app.Slider_24.Value = 2;app.Slider_25.Value = 1;
            app.Slider_26.Value = 0;app.Slider_27.Value = -1;
            app.Slider_28.Value = -1;app.Slider_29.Value = -2;
            app.Slider_30.Value = -2;app.Slider_31.Value = -2;
            
            elseif strcmp('Jazz',value) == 1
                app.Slider_1.Value = -3;
            app.Slider_2.Value = -2;app.Slider_3.Value = -2;
            app.Slider_4.Value = 0;app.Slider_5.Value = 2;
            app.Slider_6.Value = 3;app.Slider_7.Value = 1;
            app.Slider_8.Value = -2;app.Slider_9.Value = -6;
            app.Slider_10.Value = -3;app.Slider_11.Value = -1;
            app.Slider_12.Value = 0;app.Slider_13.Value = 1;
            app.Slider_14.Value = 3;app.Slider_15.Value = 6;
            app.Slider_16.Value = 5;app.Slider_17.Value = 4;
            app.Slider_18.Value = 3;app.Slider_19.Value = 2;
            app.Slider_20.Value = 2;app.Slider_21.Value = 1;
            app.Slider_22.Value = 1;app.Slider_23.Value = 0;
            app.Slider_24.Value = 0;app.Slider_25.Value = 0;
            app.Slider_26.Value = 0;app.Slider_27.Value = 0;
            app.Slider_28.Value = 0;app.Slider_29.Value = -1;
            app.Slider_30.Value = -1;app.Slider_31.Value = -1;
            
            elseif strcmp('Powerfull',value) == 1
                app.Slider_1.Value = 9;
            app.Slider_2.Value = 8;app.Slider_3.Value = 8;
            app.Slider_4.Value = 8;app.Slider_5.Value = 8;
            app.Slider_6.Value = 7;app.Slider_7.Value = 7;
            app.Slider_8.Value = 6;app.Slider_9.Value = 4;
            app.Slider_10.Value = 1;app.Slider_11.Value = -1;
            app.Slider_12.Value = -2;app.Slider_13.Value = -3;
            app.Slider_14.Value = -4;app.Slider_15.Value = -4;
            app.Slider_16.Value = -4;app.Slider_17.Value = -4;
            app.Slider_18.Value = -4;app.Slider_19.Value = -3;
            app.Slider_20.Value = -2;app.Slider_21.Value = 0;
            app.Slider_22.Value = 1;app.Slider_23.Value = 3;
            app.Slider_24.Value = 5;app.Slider_25.Value = 6;
            app.Slider_26.Value = 8;app.Slider_27.Value = 8;
            app.Slider_28.Value = 8;app.Slider_29.Value = 8;
            app.Slider_30.Value = 8;app.Slider_31.Value = 8;
            
            elseif strcmp('Shitty music',value) == 1
                app.Slider_1.Value = -13;
            app.Slider_2.Value = -13;app.Slider_3.Value = -13;
            app.Slider_4.Value = -13;app.Slider_5.Value = -13;
            app.Slider_6.Value = -13;app.Slider_7.Value = -13;
            app.Slider_8.Value = -13;app.Slider_9.Value = -13;
            app.Slider_10.Value = -13;app.Slider_11.Value = -13;
            app.Slider_12.Value = -13;app.Slider_13.Value = -13;
            app.Slider_14.Value = -13;app.Slider_15.Value = -13;
            app.Slider_16.Value = -13;app.Slider_17.Value = -13;
            app.Slider_18.Value = -13;app.Slider_19.Value = -13;
            app.Slider_20.Value = -13;app.Slider_21.Value = -13;
            app.Slider_22.Value = -13;app.Slider_23.Value = -13;
            app.Slider_24.Value = -13;app.Slider_25.Value = -13;
            app.Slider_26.Value = -13;app.Slider_27.Value = -13;
            app.Slider_28.Value = -13;app.Slider_29.Value = -13;
            app.Slider_30.Value = -13;app.Slider_31.Value = -13;
            
            elseif strcmp('MUU',value) == 1
                app.Slider_1.Value = -12;
            app.Slider_2.Value = -3;app.Slider_3.Value = 4;
            app.Slider_4.Value = 12;app.Slider_5.Value = 6;
            app.Slider_6.Value = 3;app.Slider_7.Value = 6;
            app.Slider_8.Value = 12;app.Slider_9.Value = 4;
            app.Slider_10.Value = -3;app.Slider_11.Value = -12;
            app.Slider_12.Value = 12;app.Slider_13.Value = 3;
            app.Slider_14.Value = -5;app.Slider_15.Value = -9;
            app.Slider_16.Value = -11;app.Slider_17.Value = -11;
            app.Slider_18.Value = -9;app.Slider_19.Value = -5;
            app.Slider_20.Value = 3;app.Slider_21.Value = 12;
            app.Slider_22.Value = 12;app.Slider_23.Value = 3;
            app.Slider_24.Value = -5;app.Slider_25.Value = -9;
            app.Slider_26.Value = -11;app.Slider_27.Value = -11;
            app.Slider_28.Value = -9;app.Slider_29.Value = -5;
            app.Slider_30.Value = 3;app.Slider_31.Value = 12;
            
            end
     
        end

        % Value changing function: Slider_1
        function Custom(app, event)
            app.DropDown.Value = 'Custom';            
        end

        % Value changing function: Slider_2
        function Custom2(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_3
        function Custom3(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_4
        function Custom4(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_5
        function Custom5(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_6
        function Custom6(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_7
        function Custom7(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_8
        function Custom8(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_9
        function Custom9(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_10
        function Custom10(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_11
        function Custom11(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_12
        function Custom12(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_13
        function Custom13(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_14
        function Custom14(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_15
        function Custom15(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_16
        function Custom16(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_17
        function Custom17(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_18
        function Custom18(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_19
        function Custom19(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_20
        function Custom20(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_21
        function Custom21(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_22
        function Custom22(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_23
        function Custom23(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_24
        function Custom24(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_25
        function Custom25(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_26
        function Custom26(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_27
        function Custom27(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_28
        function Custom28(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_29
        function Custom29(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_30
        function Custom30(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changing function: Slider_31
        function Custom31(app, event)
            app.DropDown.Value = 'Custom';
        end

        % Value changed function: Slider_32
        function Forward(app, event)
            if app.ff == 0
                app.ff = 1;
            else 
                app.ff = 0;
            end
        end

        % Value changed function: TrackDropDown
        function Changesong(app, event)
            value = app.TrackDropDown.Value;
             tot = audioinfo(char(app.TrackDropDown.Value));
           l = round(tot.Duration);
           
if mod(l,60) >= 10
app.Label_2.Text = strcat(num2str(floor(l/60)),':',num2str(mod(l,60)));
else
 app.Label_2.Text = strcat(num2str(floor(l/60)),':0',num2str(mod(l,60)));
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [50 14000];
            app.UIAxes.YLim = [0 0.1];
            app.UIAxes.XScale = 'log';
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.Slider_1 = uislider(app.UIFigure);
            app.Slider_1.Limits = [-13 13];
            app.Slider_1.MajorTicks = [];
            app.Slider_1.MajorTickLabels = {''};
            app.Slider_1.ValueChangingFcn = createCallbackFcn(app, @Custom, true);
            app.Slider_1.MinorTicks = [];
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-13 13];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {''};
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Custom2, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-13 13];
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MajorTickLabels = {''};
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Custom3, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-13 13];
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MajorTickLabels = {''};
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Custom4, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-13 13];
            app.Slider_5.MajorTicks = [];
            app.Slider_5.MajorTickLabels = {''};
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Custom5, true);
            app.Slider_5.MinorTicks = [];
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [-13 13];
            app.Slider_6.MajorTicks = [];
            app.Slider_6.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Custom6, true);
            app.Slider_6.MinorTicks = [];
            app.Slider_7 = uislider(app.UIFigure);
            app.Slider_7.Limits = [-13 13];
            app.Slider_7.MajorTicks = [];
            app.Slider_7.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_7.ValueChangingFcn = createCallbackFcn(app, @Custom7, true);
            app.Slider_7.MinorTicks = [];
            app.Slider_8 = uislider(app.UIFigure);
            app.Slider_8.Limits = [-13 13];
            app.Slider_8.MajorTicks = [];
            app.Slider_8.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_8.ValueChangingFcn = createCallbackFcn(app, @Custom8, true);
            app.Slider_8.MinorTicks = [];
            app.Slider_9 = uislider(app.UIFigure);
            app.Slider_9.Limits = [-13 13];
            app.Slider_9.MajorTicks = [];
            app.Slider_9.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_9.ValueChangingFcn = createCallbackFcn(app, @Custom9, true);
            app.Slider_9.MinorTicks = [];
            app.Slider_10 = uislider(app.UIFigure);
            app.Slider_10.Limits = [-13 13];
            app.Slider_10.MajorTicks = [];
            app.Slider_10.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_10.ValueChangingFcn = createCallbackFcn(app, @Custom10, true);
            app.Slider_10.MinorTicks = [];
            app.Slider_11 = uislider(app.UIFigure);
            app.Slider_11.Limits = [-13 13];
            app.Slider_11.MajorTicks = [];
            app.Slider_11.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_11.ValueChangingFcn = createCallbackFcn(app, @Custom11, true);
            app.Slider_11.MinorTicks = [];
            app.Slider_12 = uislider(app.UIFigure);
            app.Slider_12.Limits = [-13 13];
            app.Slider_12.MajorTicks = [];
            app.Slider_12.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_12.ValueChangingFcn = createCallbackFcn(app, @Custom12, true);
            app.Slider_12.MinorTicks = [];
            app.Slider_13 = uislider(app.UIFigure);
            app.Slider_13.Limits = [-13 13];
            app.Slider_13.MajorTicks = [];
            app.Slider_13.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_13.ValueChangingFcn = createCallbackFcn(app, @Custom13, true);
            app.Slider_13.MinorTicks = [];
            app.Slider_14 = uislider(app.UIFigure);
            app.Slider_14.Limits = [-13 13];
            app.Slider_14.MajorTicks = [];
            app.Slider_14.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_14.ValueChangingFcn = createCallbackFcn(app, @Custom14, true);
            app.Slider_14.MinorTicks = [];
            app.Slider_15 = uislider(app.UIFigure);
            app.Slider_15.Limits = [-13 13];
            app.Slider_15.MajorTicks = [];
            app.Slider_15.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_15.ValueChangingFcn = createCallbackFcn(app, @Custom15, true);
            app.Slider_15.MinorTicks = [];
            app.Slider_16 = uislider(app.UIFigure);
            app.Slider_16.Limits = [-13 13];
            app.Slider_16.MajorTicks = [];
            app.Slider_16.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_16.ValueChangingFcn = createCallbackFcn(app, @Custom16, true);
            app.Slider_16.MinorTicks = [];
            app.Slider_17 = uislider(app.UIFigure);
            app.Slider_17.Limits = [-13 13];
            app.Slider_17.MajorTicks = [];
            app.Slider_17.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_17.ValueChangingFcn = createCallbackFcn(app, @Custom17, true);
            app.Slider_17.MinorTicks = [];
            app.Slider_18 = uislider(app.UIFigure);
            app.Slider_18.Limits = [-13 13];
            app.Slider_18.MajorTicks = [];
            app.Slider_18.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_18.ValueChangingFcn = createCallbackFcn(app, @Custom18, true);
            app.Slider_18.MinorTicks = [];
            app.Slider_19 = uislider(app.UIFigure);
            app.Slider_19.Limits = [-13 13];
            app.Slider_19.MajorTicks = [];
            app.Slider_19.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_19.ValueChangingFcn = createCallbackFcn(app, @Custom19, true);
            app.Slider_19.MinorTicks = [];
            app.Slider_20 = uislider(app.UIFigure);
            app.Slider_20.Limits = [-13 13];
            app.Slider_20.MajorTicks = [];
            app.Slider_20.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_20.ValueChangingFcn = createCallbackFcn(app, @Custom20, true);
            app.Slider_20.MinorTicks = [];
            app.Slider_21 = uislider(app.UIFigure);
            app.Slider_21.Limits = [-13 13];
            app.Slider_21.MajorTicks = [];
            app.Slider_21.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_21.ValueChangingFcn = createCallbackFcn(app, @Custom21, true);
            app.Slider_21.MinorTicks = [];
            app.Slider_22 = uislider(app.UIFigure);
            app.Slider_22.Limits = [-13 13];
            app.Slider_22.MajorTicks = [];
            app.Slider_22.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_22.ValueChangingFcn = createCallbackFcn(app, @Custom22, true);
            app.Slider_22.MinorTicks = [];
            app.Slider_23 = uislider(app.UIFigure);
            app.Slider_23.Limits = [-13 13];
            app.Slider_23.MajorTicks = [];
            app.Slider_23.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_23.ValueChangingFcn = createCallbackFcn(app, @Custom23, true);
            app.Slider_23.MinorTicks = [];
            app.Slider_24 = uislider(app.UIFigure);
            app.Slider_24.Limits = [-13 13];
            app.Slider_24.MajorTicks = [];
            app.Slider_24.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_24.ValueChangingFcn = createCallbackFcn(app, @Custom24, true);
            app.Slider_24.MinorTicks = [];
            app.Slider_25 = uislider(app.UIFigure);
            app.Slider_25.Limits = [-13 13];
            app.Slider_25.MajorTicks = [];
            app.Slider_25.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_25.ValueChangingFcn = createCallbackFcn(app, @Custom25, true);
            app.Slider_25.MinorTicks = [];
            app.Slider_26 = uislider(app.UIFigure);
            app.Slider_26.Limits = [-13 13];
            app.Slider_26.MajorTicks = [];
            app.Slider_26.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_26.ValueChangingFcn = createCallbackFcn(app, @Custom26, true);
            app.Slider_26.MinorTicks = [];
            app.Slider_27 = uislider(app.UIFigure);
            app.Slider_27.Limits = [-13 13];
            app.Slider_27.MajorTicks = [];
            app.Slider_27.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_27.ValueChangingFcn = createCallbackFcn(app, @Custom27, true);
            app.Slider_27.MinorTicks = [];
            app.Slider_28 = uislider(app.UIFigure);
            app.Slider_28.Limits = [-13 13];
            app.Slider_28.MajorTicks = [];
            app.Slider_28.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_28.ValueChangingFcn = createCallbackFcn(app, @Custom28, true);
            app.Slider_28.MinorTicks = [];
            app.Slider_29 = uislider(app.UIFigure);
            app.Slider_29.Limits = [-13 13];
            app.Slider_29.MajorTicks = [];
            app.Slider_29.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_29.ValueChangingFcn = createCallbackFcn(app, @Custom29, true);
            app.Slider_29.MinorTicks = [];
            app.Slider_30 = uislider(app.UIFigure);
            app.Slider_30.Limits = [-13 13];
            app.Slider_30.MajorTicks = [];
            app.Slider_30.MajorTickLabels = {'', ' ', ' ', ' ', ' ', ' ', ' ', ' ', ' '};
            app.Slider_30.ValueChangingFcn = createCallbackFcn(app, @Custom30, true);
            app.Slider_30.MinorTicks = [];
            app.Slider_31 = uislider(app.UIFigure);
            app.Slider_31.Limits = [-13 13];
            app.Slider_31.MajorTicks = [];
            app.Slider_31.MajorTickLabels = {'', '', '', ''};
            app.Slider_31.ValueChangingFcn = createCallbackFcn(app, @Custom31, true);
            app.Slider_31.MinorTicks = [];
            app.TrackDropDown = uidropdown(app.UIFigure);
            app.TrackDropDown.Items = {};
            app.TrackDropDown.ValueChangedFcn = createCallbackFcn(app, @Changesong, true);
            app.TrackDropDown.Value = {};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @play, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Flat', 'Rock', 'Pop', 'Bass', 'Treble', 'Vocal', 'Classical', 'Hip-Hop', 'Dance', 'Jazz', 'Powerfull', 'Shitty music', 'MUU', 'Custom'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @Preset, true);
            app.DropDown.Value = 'Flat';
            app.RecButton = uibutton(app.UIFigure, 'push');
            app.RecButton.ButtonPushedFcn = createCallbackFcn(app, @Rec, true);
            app.RecButton.Text = 'Rec';
            app.Lamp = uilamp(app.UIFigure);
            app.Slider_32 = uislider(app.UIFigure);
            app.Slider_32.Limits = [0 1];
            app.Slider_32.MajorTicks = [];
            app.Slider_32.ValueChangedFcn = createCallbackFcn(app, @Forward, true);
            app.Slider_32.MinorTicks = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0:00';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0:00';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = '12 dB --';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = '0 dB --';
            app.dBLabel_3 = uilabel(app.UIFigure);
            app.dBLabel_3.Text = '-12 dB --';
            app.dBLabel_4 = uilabel(app.UIFigure);
            app.dBLabel_4.Text = '-- 12 dB';
            app.dBLabel_5 = uilabel(app.UIFigure);
            app.dBLabel_5.Text = '-- 0 dB';
            app.dBLabel_6 = uilabel(app.UIFigure);
            app.dBLabel_6.Text = '-- -12 dB';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '20';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '25';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '31.5';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '40';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '50';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '63';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '80';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '100';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '125';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '160';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '200';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '250';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '315';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '400';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '500';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '630';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '800';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = '1K';
            app.KLabel_2 = uilabel(app.UIFigure);
            app.KLabel_2.Text = '1.25K';
            app.KLabel_3 = uilabel(app.UIFigure);
            app.KLabel_3.Text = '1.6K';
            app.KLabel_4 = uilabel(app.UIFigure);
            app.KLabel_4.Text = '2K';
            app.KLabel_5 = uilabel(app.UIFigure);
            app.KLabel_5.Text = '2.5K';
            app.KLabel_6 = uilabel(app.UIFigure);
            app.KLabel_6.Text = '3.15K';
            app.KLabel_7 = uilabel(app.UIFigure);
            app.KLabel_7.Text = '4K';
            app.KLabel_8 = uilabel(app.UIFigure);
            app.KLabel_8.Text = '5K';
            app.KLabel_9 = uilabel(app.UIFigure);
            app.KLabel_9.Text = '6.3K';
            app.KLabel_10 = uilabel(app.UIFigure);
            app.KLabel_10.Text = '8K';
            app.KLabel_11 = uilabel(app.UIFigure);
            app.KLabel_11.Text = '10K';
            app.KLabel_12 = uilabel(app.UIFigure);
            app.KLabel_12.Text = '12.5K';
            app.KLabel_13 = uilabel(app.UIFigure);
            app.KLabel_13.Text = '16K';
            app.KLabel_14 = uilabel(app.UIFigure);
            app.KLabel_14.Text = '20K';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Value = 'On';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [1 50];
            app.Knob.MajorTicks = [1 10 20 30 40 50];
            app.Knob.MinorTicks = [];
            app.Knob.Value = 45;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioEqualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end